classdef gender_recognition_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaleFemaleVoiceRecognitionUIFigure  matlab.ui.Figure
        Image                           matlab.ui.control.Image
        StopButton                      matlab.ui.control.Button
        ListenAudioButton               matlab.ui.control.Button
        freq_2                          matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel_2       matlab.ui.control.Label
        OutputFrominbuiltFunctionLabel  matlab.ui.control.Label
        GenerateGraphButton             matlab.ui.control.Button
        OutputFromUserdefinedFunctionLabel  matlab.ui.control.Label
        SourceButton                    matlab.ui.control.Button
        file_msg                        matlab.ui.control.TextArea
        freq                            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        gender                          matlab.ui.control.EditField
        file_name                       matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        GenderLabel                     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        file_ % Description
        flag=0;
        path
        aud_file
        aud_fs=0;
    end
properties (Access = private)
    recObj % Audiorecorder object
end

methods (Access = private)
    function startRecording(app)
        Fs = 44100; % Sample rate (adjust as needed)
        nBits = 16; % Number of bits per sample
        nChannels = 1; % Number of audio channels (mono)

        % Create the audiorecorder object
        app.recObj = audiorecorder(Fs, nBits, nChannels);

        % Start recording
        record(app.recObj);
    end
end

methods (Access = public)
    % Callback for the Record Button
    function RecordButtonPushed(app, ~)
        startRecording(app);
        disp('Recording started...');
    end
end
properties (Access = private)
    recObj2 % Audiorecorder object
end


    methods (Access = public)
    
        function f0 = freq_user(app,x,fs)
            oneCast = 1;
            r       = length(x);
            c       = 1;
            WindowLength=2293;
            hopLength =441;
            numHopsFinal = ceil((r-WindowLength)/hopLength) + oneCast;
            N= 2293;
            hopSize = 441;
            numHops = ceil((r-N)/hopSize) + oneCast;
            y = zeros(N,numHops);
                for hop = 1:numHops
                    temp = x(1+hopSize*(hop-1):min(N+hopSize*(hop-1),r),1);
                    y(1:min(N,numel(temp)),hop) = temp;
                end
            
            ran=[50,400];
            fs=44100;
            edge = round(fs./fliplr(ran));
            r    = cast(size(y,1),'like',y);            
            mxl = min(edge(end),r - 1);
            m2  = 2^nextpow2(2*r - 1);
            c1  = real(ifft(abs(fft(y,m2,1)).^2,[],1))./sqrt(m2);
            Rt  = [c1(m2 - mxl + (1:mxl),:); c1(1:mxl+1,:)];          
            yRMS = sqrt(Rt(edge(end)+1,:));            
            lag  = Rt( (edge(end)+1+edge(1)):end, : );            
            yRMS = repmat(yRMS,size(lag,1),1);    
            lag = lag./yRMS;            
            domain = [zeros(edge(1)-1,size(lag,2));lag];         
            numCol = size(domain,2);
                locs = zeros(numCol,1,'like',domain);
                lower  = edge(1);
                upper  = edge(end);
                assert(upper<192000,'false');
                domain = domain(lower:upper,:);
                for c = 1:numCol
                    [~,tempLoc] = max( domain(:,c) );
                    locs(c) = tempLoc;
                end
                locs = lower + locs - 1;    
            f0 = fs./locs;
            
            bE =[50,400];
            f0(f0<bE(1))   = bE(1);
            f0(f0>bE(end)) = bE(end);
            %f1 = reshape(f0,[numHopsFinal,c]);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if app.flag==1
            [y ,Fs]=audioread(app.file_);
            frame=3500;
            app.aud_file=y;
            app.aud_fs=Fs;
            [b0,a0]=butter(2,350/(Fs/2));
            for i=1:length(y)/frame
                x=y(1+(i-1)*frame:i*frame);
                xin = abs(x);
                xin=filter(b0,a0,xin);
                xin = xin-mean(xin);
                x2=zeros(length(xin),1);
                x2(1:length(x)-1)=xin(2:length(x));
                zc=length(find((xin>0 & x2<0) | (xin<0 & x2>0)));
                F0(i)=0.5*Fs*zc/length(x);
    %fprintf('Zero-crossing F0 estimate is %3.2f Hz.\n',F0(i));
            end
            app.freq.Value=mean(F0);
            if app.freq.Value>160
                app.gender.Value='Female';
                app.file_msg.Value='Upload New File';
                
            else
                app.gender.Value='Male';
                app.file_msg.Value='Upload New File';
            end
            
            
            f0 = freq_user(app,y,Fs);
            b=mean(f0);
            app.freq_2.Value=b;
            if b>165
                app.gender_2.Value='Female';
                
            else
                app.gender_2.Value='Male';
               
            end
            plot(app.UIAxes,fftshift(abs(fft(app.aud_file))));
            title(app.UIAxes,"FFT of Audio");
            xlabel(app.UIAxes,"Frequency")
            ylabel(app.UIAxes,"Amplitude")
            
            else
                app.file_msg.Value='Incorrect Format!!';
            end
        end

        % Button pushed function: GenerateGraphButton
        function GenerateGraphButtonPushed(app, event)
            plot(app.UIAxes,app.aud_file);
            title(app.UIAxes,"Audio");
            xlabel(app.UIAxes,"Time")
            ylabel(app.UIAxes,"Amplitude")
        end

        % Callback function
        function GoButtonPushed(app, event)
            app.file_=app.file_name.Value;
            if ~isempty(app.file_name.Value) & length(app.file_name.Value)>4
                temp=app.file_name.Value;
                if length(temp)>4
                    temp=temp(length(temp)-3:length(temp));
                end
                
                if temp(1:4)=='.wav'
                    app.file_msg.Value='File Uploaded!!';
                    app.flag=1;
                    [app.aud_file ,app.aud_fs]=audioread(app.file_);
                else
                    app.file_msg.Value='Incorrect File Format!!';
                end
            end
        end

        % Button pushed function: ListenAudioButton
        function ListenAudioButtonPushed(app, event)
            sound(app.aud_file,app.aud_fs);
        end

        % Button pushed function: SourceButton
        function SourceButtonPushed(app, event)
            [file,p] = uigetfile('*.wav','Select the .wav file only');
            if file(length(file)-3:length(file))=='.wav'
                app.flag=1;
                app.file_msg.Value='File Uploaded!!';
                app.path=p;
                s = strcat(p,file);
                app.file_name.Value = file;
                app.file_=s;
                app.flag=1;
                [app.aud_file ,app.aud_fs]=audioread(app.file_);
            end       


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MaleFemaleVoiceRecognitionUIFigure = uifigure('Visible', 'off');
            app.MaleFemaleVoiceRecognitionUIFigure.Name = 'Male Female Voice Recognition';
            app.UIAxes = uiaxes(app.MaleFemaleVoiceRecognitionUIFigure);
            title(app.UIAxes, 'Gender Identification from Audio Signals')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GenderLabel = uilabel(app.MaleFemaleVoiceRecognitionUIFigure);
            app.GenderLabel.Text = 'Gender';
            app.FileNameEditFieldLabel = uilabel(app.MaleFemaleVoiceRecognitionUIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.file_name = uieditfield(app.MaleFemaleVoiceRecognitionUIFigure, 'text');
            app.gender = uieditfield(app.MaleFemaleVoiceRecognitionUIFigure, 'text');
            app.gender.Editable = 'off';
            app.CalculateButton = uibutton(app.MaleFemaleVoiceRecognitionUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.FrequencyEditFieldLabel = uilabel(app.MaleFemaleVoiceRecognitionUIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.freq = uieditfield(app.MaleFemaleVoiceRecognitionUIFigure, 'numeric');
            app.freq.Editable = 'off';
            app.file_msg = uitextarea(app.MaleFemaleVoiceRecognitionUIFigure);
            app.file_msg.Editable = 'off';
            app.file_msg.Value = {'Upload the File'};
            app.SourceButton = uibutton(app.MaleFemaleVoiceRecognitionUIFigure, 'push');
            app.SourceButton.ButtonPushedFcn = createCallbackFcn(app, @SourceButtonPushed, true);
            app.SourceButton.Text = 'Source';
            app.OutputFromUserdefinedFunctionLabel = uilabel(app.MaleFemaleVoiceRecognitionUIFigure);
            app.OutputFromUserdefinedFunctionLabel.Text = 'Output From User-defined Function';
            app.GenerateGraphButton = uibutton(app.MaleFemaleVoiceRecognitionUIFigure, 'push');
            app.GenerateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphButtonPushed, true);
            app.GenerateGraphButton.Text = 'Generate Graph';
            app.OutputFrominbuiltFunctionLabel = uilabel(app.MaleFemaleVoiceRecognitionUIFigure);
            app.OutputFrominbuiltFunctionLabel.Text = 'Output From in-built Function';
            app.FrequencyEditFieldLabel_2 = uilabel(app.MaleFemaleVoiceRecognitionUIFigure);
            app.FrequencyEditFieldLabel_2.Text = 'Frequency';
            app.freq_2 = uieditfield(app.MaleFemaleVoiceRecognitionUIFigure, 'numeric');
            app.freq_2.Editable = 'off';
            app.ListenAudioButton = uibutton(app.MaleFemaleVoiceRecognitionUIFigure, 'push');
            app.ListenAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ListenAudioButtonPushed, true);
            app.ListenAudioButton.Text = 'Listen Audio';
            app.StopButton = uibutton(app.MaleFemaleVoiceRecognitionUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Image = uiimage(app.MaleFemaleVoiceRecognitionUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'Combotrans.svg.png');
            % Show the figure after all components are created
            app.MaleFemaleVoiceRecognitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gender_recognition_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaleFemaleVoiceRecognitionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaleFemaleVoiceRecognitionUIFigure)
        end
    end
end